classdef ctrlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControlUIFigure              matlab.ui.Figure
        TomJerryMissionControlLabel  matlab.ui.control.Label
        ControlPanel                 matlab.ui.container.Panel
        SetspeedButton               matlab.ui.control.Button
        MissionAbortButton           matlab.ui.control.Button
        LinefollowerButton           matlab.ui.control.Button
        ChangeleftButton             matlab.ui.control.Button
        ChangerightButton            matlab.ui.control.Button
        SpeedleftEditFieldLabel      matlab.ui.control.Label
        SpeedleftEditField           matlab.ui.control.NumericEditField
        SpeedrightEditFieldLabel     matlab.ui.control.Label
        SpeedrightEditField          matlab.ui.control.NumericEditField
        SidebysideButton             matlab.ui.control.Button
        ChangeleaderButton           matlab.ui.control.Button
        OvertakeleftButton           matlab.ui.control.Button
        OvertakerightButton          matlab.ui.control.Button
        MergeleftButton              matlab.ui.control.Button
        MergerightButton             matlab.ui.control.Button
        FanningleftButton            matlab.ui.control.Button
        FanningrightButton           matlab.ui.control.Button
        PositionInfoPanel            matlab.ui.container.Panel
        JerryXEditFieldLabel         matlab.ui.control.Label
        JerryXEditField              matlab.ui.control.NumericEditField
        TomXEditFieldLabel           matlab.ui.control.Label
        TomXEditField                matlab.ui.control.NumericEditField
        TomYEditFieldLabel           matlab.ui.control.Label
        TomYEditField                matlab.ui.control.NumericEditField
        JerryYEditFieldLabel         matlab.ui.control.Label
        JerryYEditField              matlab.ui.control.NumericEditField
        JerryGPSLampLabel            matlab.ui.control.Label
        JerryGPSLamp                 matlab.ui.control.Lamp
        TomGPSLampLabel              matlab.ui.control.Label
        TomGPSLamp                   matlab.ui.control.Lamp
        AnglebetweenJerryandTomEditFieldLabel  matlab.ui.control.Label
        AnglebetweenJerryandTomEditField  matlab.ui.control.NumericEditField
        SpeedInfoPanel               matlab.ui.container.Panel
        PIDGaugeLabel                matlab.ui.control.Label
        PIDGauge                     matlab.ui.control.SemicircularGauge
        JerrySpeedGaugeLabel         matlab.ui.control.Label
        JerrySpeedGauge              matlab.ui.control.SemicircularGauge
        TomSpeedGaugeLabel           matlab.ui.control.Label
        TomSpeedGauge                matlab.ui.control.SemicircularGauge
        GeneralInfoPanel             matlab.ui.container.Panel
        LeaderEditFieldLabel         matlab.ui.control.Label
        LeaderEditField              matlab.ui.control.EditField
        ConsoleTextAreaLabel         matlab.ui.control.Label
        ConsoleTextArea              matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Button pushed function: SetspeedButton
        function SetspeedButtonPushed(app, event)
            setSpeedLeft(app.SpeedleftEditField.Value);
            setSpeedRight(app.SpeedrightEditField.Value);
            setPublishFlag(0);
        end

        % Button pushed function: LinefollowerButton
        function LinefollowerButtonPushed(app, event)
            setArduinoState(1);
            setPublishFlag(2);
        end

        % Button pushed function: MissionAbortButton
        function MissionAbortButtonPushed(app, event)
            setArduinoState(0);
            setPublishFlag(0);
        end

        % Button pushed function: ChangeleftButton
        function ChangeleftButtonPushed(app, event)
            setArduinoState(2);
            setPublishFlag(0);
        end

        % Button pushed function: ChangerightButton
        function ChangerightButtonPushed(app, event)
            setArduinoState(3);
            setPublishFlag(0);
        end

        % Button pushed function: SidebysideButton
        function SidebysideButtonPushed(app, event)
            setArduinoState(1);
            setMatlabState(1);
            setPublishFlag(2);
        end

        % Button pushed function: ChangeleaderButton
        function ChangeleaderButtonPushed(app, event)
            setLeader(1);
        end

        % Button pushed function: OvertakeleftButton
        function OvertakeleftButtonPushed(app, event)
            setMatlabState(2);
            setPublishFlag(0);
        end

        % Button pushed function: OvertakerightButton
        function OvertakerightButtonPushed(app, event)
            setMatlabState(3);
            setPublishFlag(0);
        end

        % Button pushed function: MergeleftButton
        function MergeleftButtonPushed(app, event)
            setMatlabState(4);
            setPublishFlag(0);
        end

        % Button pushed function: MergerightButton
        function MergerightButtonPushed(app, event)
            setMatlabState(5);
            setPublishFlag(0);
        end

        % Button pushed function: FanningleftButton
        function FanningleftButtonPushed(app, event)
            setMatlabState(6);
            setPublishFlag(0);
        end

        % Button pushed function: FanningrightButton
        function FanningrightButtonPushed(app, event)
            setMatlabState(7);
            setPublishFlag(0);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControlUIFigure = uifigure;
            app.ControlUIFigure.Name = 'Control';
            app.TomJerryMissionControlLabel = uilabel(app.ControlUIFigure);
            app.TomJerryMissionControlLabel.Text = 'Tom & Jerry - Mission Control';
            app.ControlPanel = uipanel(app.ControlUIFigure);
            app.ControlPanel.Title = 'Control';
            app.SetspeedButton = uibutton(app.ControlPanel, 'push');
            app.SetspeedButton.ButtonPushedFcn = createCallbackFcn(app, @SetspeedButtonPushed, true);
            app.SetspeedButton.Text = 'Set speed';
            app.MissionAbortButton = uibutton(app.ControlPanel, 'push');
            app.MissionAbortButton.ButtonPushedFcn = createCallbackFcn(app, @MissionAbortButtonPushed, true);
            app.MissionAbortButton.Text = 'Mission Abort!';
            app.LinefollowerButton = uibutton(app.ControlPanel, 'push');
            app.LinefollowerButton.ButtonPushedFcn = createCallbackFcn(app, @LinefollowerButtonPushed, true);
            app.LinefollowerButton.Text = 'Line follower';
            app.ChangeleftButton = uibutton(app.ControlPanel, 'push');
            app.ChangeleftButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeleftButtonPushed, true);
            app.ChangeleftButton.Text = 'Change left';
            app.ChangerightButton = uibutton(app.ControlPanel, 'push');
            app.ChangerightButton.ButtonPushedFcn = createCallbackFcn(app, @ChangerightButtonPushed, true);
            app.ChangerightButton.Text = 'Change right';
            app.SpeedleftEditFieldLabel = uilabel(app.ControlPanel);
            app.SpeedleftEditFieldLabel.Text = 'Speed left';
            app.SpeedleftEditField = uieditfield(app.ControlPanel, 'numeric');
            app.SpeedleftEditField.Limits = [-250 250];
            app.SpeedleftEditField.ValueDisplayFormat = '%.0f';
            app.SpeedleftEditField.Value = 80;
            app.SpeedrightEditFieldLabel = uilabel(app.ControlPanel);
            app.SpeedrightEditFieldLabel.Text = 'Speed right';
            app.SpeedrightEditField = uieditfield(app.ControlPanel, 'numeric');
            app.SpeedrightEditField.Limits = [-250 250];
            app.SpeedrightEditField.ValueDisplayFormat = '%.0f';
            app.SpeedrightEditField.Value = 80;
            app.SidebysideButton = uibutton(app.ControlPanel, 'push');
            app.SidebysideButton.ButtonPushedFcn = createCallbackFcn(app, @SidebysideButtonPushed, true);
            app.SidebysideButton.Text = 'Side-by-side';
            app.ChangeleaderButton = uibutton(app.ControlPanel, 'push');
            app.ChangeleaderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeleaderButtonPushed, true);
            app.ChangeleaderButton.Text = 'Change leader';
            app.OvertakeleftButton = uibutton(app.ControlPanel, 'push');
            app.OvertakeleftButton.ButtonPushedFcn = createCallbackFcn(app, @OvertakeleftButtonPushed, true);
            app.OvertakeleftButton.Text = 'Overtake left';
            app.OvertakerightButton = uibutton(app.ControlPanel, 'push');
            app.OvertakerightButton.ButtonPushedFcn = createCallbackFcn(app, @OvertakerightButtonPushed, true);
            app.OvertakerightButton.Text = 'Overtake right';
            app.MergeleftButton = uibutton(app.ControlPanel, 'push');
            app.MergeleftButton.ButtonPushedFcn = createCallbackFcn(app, @MergeleftButtonPushed, true);
            app.MergeleftButton.Text = 'Merge left';
            app.MergerightButton = uibutton(app.ControlPanel, 'push');
            app.MergerightButton.ButtonPushedFcn = createCallbackFcn(app, @MergerightButtonPushed, true);
            app.MergerightButton.Text = 'Merge right';
            app.FanningleftButton = uibutton(app.ControlPanel, 'push');
            app.FanningleftButton.ButtonPushedFcn = createCallbackFcn(app, @FanningleftButtonPushed, true);
            app.FanningleftButton.Text = 'Fanning left';
            app.FanningrightButton = uibutton(app.ControlPanel, 'push');
            app.FanningrightButton.ButtonPushedFcn = createCallbackFcn(app, @FanningrightButtonPushed, true);
            app.FanningrightButton.Text = 'Fanning right';
            app.PositionInfoPanel = uipanel(app.ControlUIFigure);
            app.PositionInfoPanel.Title = 'Position Info';
            app.JerryXEditFieldLabel = uilabel(app.PositionInfoPanel);
            app.JerryXEditFieldLabel.Text = 'Jerry X';
            app.JerryXEditField = uieditfield(app.PositionInfoPanel, 'numeric');
            app.JerryXEditField.ValueDisplayFormat = '%.0f';
            app.JerryXEditField.Editable = 'off';
            app.TomXEditFieldLabel = uilabel(app.PositionInfoPanel);
            app.TomXEditFieldLabel.Text = 'Tom X';
            app.TomXEditField = uieditfield(app.PositionInfoPanel, 'numeric');
            app.TomXEditField.ValueDisplayFormat = '%.0f';
            app.TomXEditField.Editable = 'off';
            app.TomYEditFieldLabel = uilabel(app.PositionInfoPanel);
            app.TomYEditFieldLabel.Text = 'Tom Y';
            app.TomYEditField = uieditfield(app.PositionInfoPanel, 'numeric');
            app.TomYEditField.ValueDisplayFormat = '%.0f';
            app.TomYEditField.Editable = 'off';
            app.JerryYEditFieldLabel = uilabel(app.PositionInfoPanel);
            app.JerryYEditFieldLabel.Text = 'Jerry Y';
            app.JerryYEditField = uieditfield(app.PositionInfoPanel, 'numeric');
            app.JerryYEditField.ValueDisplayFormat = '%.0f';
            app.JerryYEditField.Editable = 'off';
            app.JerryGPSLampLabel = uilabel(app.PositionInfoPanel);
            app.JerryGPSLampLabel.Text = 'Jerry GPS';
            app.JerryGPSLamp = uilamp(app.PositionInfoPanel);
            app.TomGPSLampLabel = uilabel(app.PositionInfoPanel);
            app.TomGPSLampLabel.Text = 'Tom GPS';
            app.TomGPSLamp = uilamp(app.PositionInfoPanel);
            app.AnglebetweenJerryandTomEditFieldLabel = uilabel(app.PositionInfoPanel);
            app.AnglebetweenJerryandTomEditFieldLabel.Text = 'Angle between Jerry and Tom';
            app.AnglebetweenJerryandTomEditField = uieditfield(app.PositionInfoPanel, 'numeric');
            app.AnglebetweenJerryandTomEditField.ValueDisplayFormat = '%.4f';
            app.AnglebetweenJerryandTomEditField.Editable = 'off';
            app.SpeedInfoPanel = uipanel(app.ControlUIFigure);
            app.SpeedInfoPanel.Title = 'Speed Info';
            app.PIDGaugeLabel = uilabel(app.SpeedInfoPanel);
            app.PIDGaugeLabel.Text = 'PID';
            app.PIDGauge = uigauge(app.SpeedInfoPanel, 'semicircular');
            app.PIDGauge.Limits = [-100 100];
            app.JerrySpeedGaugeLabel = uilabel(app.SpeedInfoPanel);
            app.JerrySpeedGaugeLabel.Text = 'Jerry Speed';
            app.JerrySpeedGauge = uigauge(app.SpeedInfoPanel, 'semicircular');
            app.JerrySpeedGauge.Limits = [-250 250];
            app.JerrySpeedGauge.MajorTicks = [-250 -125 0 125 250];
            app.TomSpeedGaugeLabel = uilabel(app.SpeedInfoPanel);
            app.TomSpeedGaugeLabel.Text = 'Tom Speed';
            app.TomSpeedGauge = uigauge(app.SpeedInfoPanel, 'semicircular');
            app.TomSpeedGauge.Limits = [-250 250];
            app.GeneralInfoPanel = uipanel(app.ControlUIFigure);
            app.GeneralInfoPanel.Title = 'General Info';
            app.LeaderEditFieldLabel = uilabel(app.GeneralInfoPanel);
            app.LeaderEditFieldLabel.Text = 'Leader';
            app.LeaderEditField = uieditfield(app.GeneralInfoPanel, 'text');
            app.LeaderEditField.Editable = 'off';
            app.ConsoleTextAreaLabel = uilabel(app.ControlUIFigure);
            app.ConsoleTextAreaLabel.Text = 'Console';
            app.ConsoleTextArea = uitextarea(app.ControlUIFigure);
            app.ConsoleTextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ctrlGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ControlUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControlUIFigure)
        end
    end
end